classdef EmocromoAnalyzer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        StoricoSQLitePanel        matlab.ui.container.Panel
        HCTTextArea               matlab.ui.control.TextArea
        AnomaliaTextArea          matlab.ui.control.TextArea
        EsitoTextArea             matlab.ui.control.TextArea
        MCHCEditField             matlab.ui.control.NumericEditField
        MCHCEditFieldLabel        matlab.ui.control.Label
        MCHEditField              matlab.ui.control.NumericEditField
        MCHEditFieldLabel         matlab.ui.control.Label
        MCVEditField              matlab.ui.control.NumericEditField
        MCVEditFieldLabel         matlab.ui.control.Label
        UITable                   matlab.ui.control.Table
        DatiPazienteedEsamePanel  matlab.ui.container.Panel
        EtEditField               matlab.ui.control.NumericEditField
        EtEditFieldLabel          matlab.ui.control.Label
        AnalizzaeSalvaButton      matlab.ui.control.Button
        PLTEditField              matlab.ui.control.NumericEditField
        PLTEditFieldLabel         matlab.ui.control.Label
        WBCEditField              matlab.ui.control.NumericEditField
        WBCEditFieldLabel         matlab.ui.control.Label
        RBCEditField              matlab.ui.control.NumericEditField
        RBCEditFieldLabel         matlab.ui.control.Label
        HGBEditField              matlab.ui.control.NumericEditField
        HGBEditFieldLabel         matlab.ui.control.Label
        SessoDropDown             matlab.ui.control.DropDown
        SessoDropDownLabel        matlab.ui.control.Label
        DiagnosieGraficiPanel     matlab.ui.container.Panel
        EsitoLabel                matlab.ui.control.Label
        UIAxes                    matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
   
    try
        conn = sqlite('emocromo.db');
    catch
        app.UITable.Data = [];
        app.UITable.ColumnName = {'Codice', 'Sesso', 'HGB', 'RBC', 'WBC', 'PLT', 'Diagnosi'};
        return;
    end
    
    sqlQuery = ['SELECT P.Codice, P.Sesso, ' ...
            'COALESCE(E.HGB, 0) AS HGB, ' ...
            'COALESCE(E.RBC, 0) AS RBC, ' ...
            'COALESCE(E.WBC, 0) AS WBC, ' ...
            'COALESCE(E.PLT, 0) AS PLT, ' ...
            'COALESCE(R.Esito_Diagnostico, ''In attesa'') AS Diagnosi ' ...
            'FROM PAZIENTE P ' ...
            'LEFT JOIN ESAME_EMOCROMO E ON P.ID_Paziente = E.ID_Paziente ' ...
            'LEFT JOIN REFERTO_ANALISI R ON E.ID_Esame = R.ID_Esame ' ...
            'ORDER BY P.ID_Paziente DESC ' ...
            'LIMIT 100;'];
            
    dati = fetch(conn, sqlQuery);
    close(conn);

            if ~isempty(dati)
                app.UITable.Data = dati;
                app.UITable.ColumnName = {'Codice', 'Sesso', 'HGB', 'RBC', 'WBC', 'PLT', 'Diagnosi'};
            else
                app.UITable.Data = [];
                app.UITable.ColumnName = {'Codice', 'Sesso', 'HGB', 'RBC', 'WBC', 'PLT', 'Diagnosi'};
            end

        end

        % Button pushed function: AnalizzaeSalvaButton
        function AnalizzaeSalvaButtonPushed(app, event)
        sesso = app.SessoDropDown.Value;
            eta = str2double(app.EtEditField.Value);
            hgb = str2double(app.HGBEditField.Value);
            rbc = str2double(app.RBCEditField.Value);
            wbc = str2double(app.WBCEditField.Value);
            plt = str2double(app.PLTEditField.Value);
            mcv = str2double(app.MCVEditField.Value);
            mch = str2double(app.MCHEditField.Value);
            mchc = str2double(app.MCHCEditField.Value);

            if isempty(eta) || isempty(hgb) || isempty(rbc) || isempty(wbc) || isempty(plt) || ...
               isempty(mcv) || isempty(mch) || isempty(mchc) || ...
               eta <= 0 || hgb <= 0 || rbc <= 0 || wbc <= 0 || plt <= 0 || ...
               mcv <= 0 || mch <= 0 || mchc <= 0

                uialert(app.UIFigure, 'Inserire valori validi e maggiori di zero per tutti i campi.', 'Input non valido');
                return;
            end

            hct = rbc * mcv / 10;

            [esito, anomaliaPerc, ~] = diagnostica_emocromo(sesso, hgb, wbc, plt, mcv, mch, mchc);

            app.HCTTextArea.Value = sprintf('%.2f', hct);
            app.EsitoTextArea.Value = esito;
            app.AnomaliaTextArea.Value = sprintf('%d%%', anomaliaPerc);
            app.EsitoLabel.Text = esito;
            
            if strcmp(esito, "Valori nella Norma")
                app.EsitoLabel.FontColor = [0 0.6 0];
            else
                app.EsitoLabel.FontColor = [0.8 0 0];
            end

            try
                conn = sqlite('emocromo.db');
            catch
                uialert(app.UIFigure, 'Database non trovato. Esegui prima crea_database.m', 'Errore DB');
                return;
            end

            annoCorrente = year(datetime('now'));
            annoNascita = annoCorrente - eta;
            dataNascita = sprintf('%d-01-01', annoNascita);

            codiceTemp = sprintf('PAZ_%d', int64(posixtime(datetime('now')) * 1000));
            sqlPaz = sprintf("INSERT INTO PAZIENTE (Codice, Data_Nascita, Sesso) VALUES ('%s', '%s', '%s');", ...
                codiceTemp, dataNascita, sesso);
            exec(conn, sqlPaz);

            resultPaz = fetch(conn, "SELECT last_insert_rowid();");
            idPaziente = resultPaz{1,1};

            codiceDef = sprintf('PAZ_%03d', idPaziente);
            exec(conn, sprintf("UPDATE PAZIENTE SET Codice = '%s' WHERE ID_Paziente = %d;", codiceDef, idPaziente));

            sqlEsame = sprintf("INSERT INTO ESAME_EMOCROMO (ID_Paziente, RBC, HGB, HCT, WBC, PLT, MCV, MCH, MCHC) VALUES (%d, %.2f, %.2f, %.2f, %.0f, %.0f, %.2f, %.2f, %.2f);", ...
                idPaziente, rbc, hgb, hct, wbc, plt, mcv, mch, mchc);
            exec(conn, sqlEsame);

            resultEsame = fetch(conn, "SELECT last_insert_rowid();");
            idEsame = resultEsame{1,1};

            sqlRef = sprintf("INSERT INTO REFERTO_ANALISI (ID_Esame, Esito_Diagnostico, Indice_Anomalia_Perc) VALUES (%d, '%s', %.1f);", ...
                idEsame, esito, anomaliaPerc);
            exec(conn, sqlRef);

            close(conn);

            try
                conn = sqlite('emocromo.db');
                sqlQuery = ['SELECT P.Codice, P.Sesso, ' ...
                            'COALESCE(E.HGB, 0) AS HGB, ' ...
                            'COALESCE(E.RBC, 0) AS RBC, ' ...
                            'COALESCE(E.WBC, 0) AS WBC, ' ...
                            'COALESCE(E.PLT, 0) AS PLT, ' ...
                            'COALESCE(R.Esito_Diagnostico, ''In attesa'') AS Diagnosi ' ...
                            'FROM PAZIENTE P ' ...
                            'LEFT JOIN ESAME_EMOCROMO E ON P.ID_Paziente = E.ID_Paziente ' ...
                            'LEFT JOIN REFERTO_ANALISI R ON E.ID_Esame = R.ID_Esame ' ...
                            'ORDER BY P.ID_Paziente DESC ' ...
                            'LIMIT 100;'];
                datiAggiornati = fetch(conn, sqlQuery);
                close(conn);

                app.UITable.Data = datiAggiornati;
                app.UITable.ColumnName = {'Codice', 'Sesso', 'HGB', 'RBC', 'WBC', 'PLT', 'Diagnosi'};
            catch
            end

            hgb_pct = (hgb / 14.0) * 100;
            rbc_pct = (rbc / 4.5) * 100;
            wbc_pct = (wbc / 7500.0) * 100;
            plt_pct = (plt / 250000.0) * 100;

            valoriPct = [hgb_pct, rbc_pct, wbc_pct, plt_pct];
            etichette = {'HGB', 'RBC', 'WBC', 'PLT'};

            bar(app.UIAxes, valoriPct, 'FaceColor', [0.2 0.4 0.8]);
            app.UIAxes.XTick = 1:4;
            app.UIAxes.XTickLabel = etichette;
            ylabel(app.UIAxes, 'Scostamento dalla Norma (%)');
            title(app.UIAxes, 'Analisi Parametri Ematologici');
            grid(app.UIAxes, 'on');
            yline(app.UIAxes, 100, '--r', 'Norma (100%)', 'LineWidth', 1.5);

            uialert(app.UIFigure, sprintf('Paziente salvato con successo.\nDiagnosi: %s', esito), 'Operazione completata');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DiagnosieGraficiPanel = uipanel(app.UIFigure);
            app.DiagnosieGraficiPanel.Title = 'Diagnosi e Grafici';
            app.UIAxes = uiaxes(app.DiagnosieGraficiPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.EsitoLabel = uilabel(app.DiagnosieGraficiPanel);
            app.EsitoLabel.Text = 'Esito';
            app.DatiPazienteedEsamePanel = uipanel(app.UIFigure);
            app.DatiPazienteedEsamePanel.Title = 'Dati Paziente ed Esame';
            app.SessoDropDownLabel = uilabel(app.DatiPazienteedEsamePanel);
            app.SessoDropDownLabel.Text = 'Sesso';
            app.SessoDropDown = uidropdown(app.DatiPazienteedEsamePanel);
            app.SessoDropDown.Items = {'M', 'F'};
            app.SessoDropDown.Value = 'M';
            app.HGBEditFieldLabel = uilabel(app.DatiPazienteedEsamePanel);
            app.HGBEditFieldLabel.Text = 'HGB';
            app.HGBEditField = uieditfield(app.DatiPazienteedEsamePanel, 'numeric');
            app.RBCEditFieldLabel = uilabel(app.DatiPazienteedEsamePanel);
            app.RBCEditFieldLabel.Text = 'RBC';
            app.RBCEditField = uieditfield(app.DatiPazienteedEsamePanel, 'numeric');
            app.WBCEditFieldLabel = uilabel(app.DatiPazienteedEsamePanel);
            app.WBCEditFieldLabel.Text = 'WBC';
            app.WBCEditField = uieditfield(app.DatiPazienteedEsamePanel, 'numeric');
            app.PLTEditFieldLabel = uilabel(app.DatiPazienteedEsamePanel);
            app.PLTEditFieldLabel.Text = 'PLT';
            app.PLTEditField = uieditfield(app.DatiPazienteedEsamePanel, 'numeric');
            app.AnalizzaeSalvaButton = uibutton(app.DatiPazienteedEsamePanel, 'push');
            app.AnalizzaeSalvaButton.ButtonPushedFcn = createCallbackFcn(app, @AnalizzaeSalvaButtonPushed, true);
            app.AnalizzaeSalvaButton.Text = 'Analizza e Salva';
            app.EtEditFieldLabel = uilabel(app.DatiPazienteedEsamePanel);
            app.EtEditFieldLabel.Text = 'Età';
            app.EtEditField = uieditfield(app.DatiPazienteedEsamePanel, 'numeric');
            app.StoricoSQLitePanel = uipanel(app.UIFigure);
            app.StoricoSQLitePanel.Title = 'Storico SQLite';
            app.UITable = uitable(app.StoricoSQLitePanel);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.MCVEditFieldLabel = uilabel(app.StoricoSQLitePanel);
            app.MCVEditFieldLabel.Text = 'MCV';
            app.MCVEditField = uieditfield(app.StoricoSQLitePanel, 'numeric');
            app.MCHEditFieldLabel = uilabel(app.StoricoSQLitePanel);
            app.MCHEditFieldLabel.Text = 'MCH';
            app.MCHEditField = uieditfield(app.StoricoSQLitePanel, 'numeric');
            app.MCHCEditFieldLabel = uilabel(app.StoricoSQLitePanel);
            app.MCHCEditFieldLabel.Text = 'MCHC';
            app.MCHCEditField = uieditfield(app.StoricoSQLitePanel, 'numeric');
            app.EsitoTextArea = uitextarea(app.StoricoSQLitePanel);
            app.AnomaliaTextArea = uitextarea(app.StoricoSQLitePanel);
            app.HCTTextArea = uitextarea(app.StoricoSQLitePanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EmocromoAnalyzer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end